classdef Metodos_numericos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Bienvenida                  matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        descripcion_cap3            matlab.ui.control.Label
        descripcion_cap2            matlab.ui.control.Label
        descripcion_cap1            matlab.ui.control.Label
        Intro                       matlab.ui.control.Label
        Titulo                      matlab.ui.control.Label
        Cap1metodosTab              matlab.ui.container.Tab
        descripcion_result          matlab.ui.control.Label
        descripcion_method          matlab.ui.control.Label
        tab_title                   matlab.ui.control.Label
        Contenedor_metodos          matlab.ui.container.ButtonGroup
        RaicesMultiples2Button      matlab.ui.control.RadioButton
        RaicesMultiplesButton       matlab.ui.control.RadioButton
        SecanteButton               matlab.ui.control.RadioButton
        NewtonButton                matlab.ui.control.RadioButton
        ReglaFalsaButton            matlab.ui.control.RadioButton
        PuntoFijoButton             matlab.ui.control.RadioButton
        BiseccionButton             matlab.ui.control.RadioButton
        Tabla_resultado             matlab.ui.control.Table
        Boton_resolver              matlab.ui.control.Button
        PuntofinalEditFieldLabel    matlab.ui.control.Label
        Punto_final                 matlab.ui.control.NumericEditField
        Punto_inicial               matlab.ui.control.NumericEditField
        PuntoinicialEditFieldLabel  matlab.ui.control.Label
        Entrada_g                   matlab.ui.control.EditField
        GxEditFieldLabel            matlab.ui.control.Label
        Multiplicidad               matlab.ui.control.Spinner
        MultiplicidadSpinnerLabel   matlab.ui.control.Label
        Control_Error               matlab.ui.control.DropDown
        TipodeerrorDropDownLabel    matlab.ui.control.Label
        Num_Iteraciones             matlab.ui.control.NumericEditField
        NumIteracionesLabel         matlab.ui.control.Label
        Error                       matlab.ui.control.NumericEditField
        ErrorLabel                  matlab.ui.control.Label
        Entrada_Fun                 matlab.ui.control.EditField
        FxLabel                     matlab.ui.control.Label
        Grafica                     matlab.ui.control.UIAxes
        Cap2Tab                     matlab.ui.container.Tab
        Cap3Tab                     matlab.ui.container.Tab
        ContextMenu                 matlab.ui.container.ContextMenu
    end

    
    properties (Access = private)
        oldpaht = addpath(genpath('ANALISIS_proyecto/funciones_cap'))
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: Contenedor_metodos
        function Contenedor_metodosSelectionChanged2(app, event)
            boton = app.Contenedor_metodos.SelectedObject.Text;
            app.descripcion_method.Text = boton;
            switch boton
                case 'Biseccion'
           
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                
                    app.Multiplicidad.Enable="off";                   
                    app.Punto_final.Enable='off';
                    app.Entrada_g.Enable='off';

                    app.descripcion_method.Text = sprintf([boton ...
                        '\n - función continua' ...
                        '\n - $[a, b]$, con la condición $f (a) * f (b) < 0$' ...
                        '\n - la raíz $x_v$, es contienidad en $[a, b]$' ...
                        '\n - La sucesión $x_{n}$ generada por ' ...
                        'la aplicación del método cumple que para todo $n > 0$' ...
                        '\n |x_v − x_{n}| < (b - a)/2^n$']);
                    
                case 'Punto Fijo'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';


                    app.Multiplicidad.Enable="off";    
                    app.Punto_final.Enable='off';

                    app.descripcion_method.Text = sprintf([boton ...
                        '\n - Si g es una función continua en el intervalo [a, b] ' ...
                        'y x esta en $[a, b]$ si g(x) esta en [a, b] ' ...
                        'entonces g tiene un punto fijo en [a, b]. \n - para todo ' ...
                        'x en (a, b), g′(x) existe y $|g′(x)| ≤ k < 1$, entonces' ...
                        ' g tiene un único punto fijo p en [a, b].']);

                case 'Regla Falsa'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                    app.Multiplicidad.Enable="off";
                    app.Entrada_g.Enable='off';

                    app.descripcion_method.Text = sprintf([boton ...
                        '\n - Si g es una función continua en el intervalo [a, b] ' ...
                        'y x esta en $[a, b]$ si g(x) esta en [a, b] ' ...
                        'entonces g tiene un punto fijo en [a, b]. \n - para todo ' ...
                        'x en (a, b), g′(x) existe y $|g′(x)| ≤ k < 1$, entonces' ...
                        ' g tiene un único punto fijo p en [a, b].']);

                case 'Newton'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                    app.Multiplicidad.Enable="off";
                    app.Entrada_g.Enable="off";
                    app.Punto_final.Enable="off";

                    app.descripcion_method.Text = sprintf([boton ...
                        '\n f es continua y tiene primera derivada en [a, b] ' ...
                        'con la raíz $x_v$ en [a, b] tal que $f (x_v) = 0$' ...
                        'y $f^{(1)}(x_v)$ diferente de cero existe $d > 0$' ...
                        'tal que el método converge a $x_v$ para cualquier ' ...
                        'valor inicial $x_0$ en $[x_v − d, x_v + d]$']);

                case 'Secante'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                    app.Multiplicidad.Enable="off";
                    app.Entrada_g.Enable="off";

                    app.descripcion_method.Text = sprintf(boton);

                case'Raices Multiples'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';
                    
                     app.Entrada_g.Enable="off";
                     app.Punto_final.Enable='off';

                     app.descripcion_method.Text = sprintf([boton]);

                case'Raices Multiples 2'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';
                    
                    app.Entrada_g.Enable="off";
                    app.Punto_final.Enable='off';
                    app.Multiplicidad.Enable="off";

                    app.descripcion_method.Text = sprintf([boton]);
            end
            
            title(app.Grafica, boton);

        end

        % Button pushed function: Boton_resolver
        function Boton_resolverButtonPushed(app, event)
            metodo = app.Contenedor_metodos.SelectedObject.Text;

            switch metodo

                case 'Biseccion'
                    % setup
                    Funcion = str2sym(app.Entrada_Fun.Value);
                    Error_metodo = app.Error.Value;
                    Num_iter = app.Num_Iteraciones.Value;
                    Control_E = app.Control_Error.Value;
                    Punto_I = app.Punto_inicial.Value;

                    % metodo
                    [resultado,iteraciones,tabla] = Biseccion_lm(Punto_I,Error_metodo,Num_iter,Funcion);

                    % tabla
                    app.Tabla_resultado.RowName = 'numbered';
                    app.Tabla_resultado.Data = tabla;
                    app.Tabla_resultado.ColumnName = {"x_n","f(x_n)","Error"};

                case 'Punto Fijo'

                    % setup
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Funcion_g=str2sym(app.Entrada_g.Value);
                    Punto_I=app.Punto_inicial.Value;

                    % method
                    [resultado, tabla] = Punto_fijo(Punto_I,Error_metodo,Control_E,Num_iter,Funcion,Funcion_g);

                    % table
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={"Puntos (x)","Valor de la Funcion","Error"};

                case 'Regla Falsa'
                    % setup
                    Funcion = str2sym(app.Entrada_Fun.Value);
                    Error_metodo = app.Error.Value;
                    Num_iter = app.Num_Iteraciones.Value;
                    Control_E = app.Control_Error.Value; % tipo error
                    Intervalo = [app.Punto_inicial.Value app.Punto_final.Value];

                    % method
                    [resultado,tabla] = Regla_Falsa(Funcion,Intervalo,Error_metodo,Num_iter);

                    % table
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={'xa','tfa','xb','tfb','txnuevo','tfn'};

                case 'Newton'
                    % setup
                    Funcion = str2sym(app.Entrada_Fun.Value);
                    Error_metodo = app.Error.Value;
                    Num_iter = app.Num_Iteraciones.Value;
                    Control_E = app.Control_Error.Value;
                    Punto_I = app.Punto_inicial.Value;

                    % method
                    [resultado,tabla] = Newton_LM(Punto_I,Error_metodo,Num_iter,Funcion,Control_E);

                    % table
                    app.Tabla_resultado.Data = tabla;
                    app.Tabla_resultado.RowName = 'numbered';
                    app.Tabla_resultado.ColumnName = {'x_n','f(x_n)','error'};
                    
                case 'Secante'
                    % setup
                    Funcion = str2sym(app.Entrada_Fun.Value);
                    Error_metodo = app.Error.Value;
                    Num_iter = app.Num_Iteraciones.Value;
                    Control_E = app.Control_Error.Value;
                    Intervalo = [app.Punto_inicial.Value app.Punto_final.Value];

                    % method
                    [resultado,tabla] = Secante(Funcion,Intervalo,Error_metodo,Control_E);

                    % table
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data = tabla;
                    app.Tabla_resultado.ColumnName={'Punto t','error t'};

                case'Raices Multiples'
                    % setup
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Punto_I=app.Punto_inicial.Value;
                    Multiplicidad_v=app.Multiplicidad.Value;

                    % method
                    [resultado,tabla] = Newton_Mult(Funcion,Punto_I,Error_metodo,Multiplicidad_v,Num_iter,Control_E);

                    % result
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={'Punto t','error t'};

                case'Raices Multiples 2'
                    % setup
                    Funcion = str2sym(app.Entrada_Fun.Value);
                    Error_metodo = app.Error.Value;
                    Num_iter = app.Num_Iteraciones.Value;
                    Control_E = app.Control_Error.Value;
                    Punto_I = app.Punto_inicial.Value;

                    % method
                    [resultado,tabla] = Newton_mult_2(Funcion,Punto_I,Error_metodo,Num_iter,Control_E);

                    % table
                    app.Tabla_resultado.RowName = 'numbered';
                    app.Tabla_resultado.Data = tabla;
                    app.Tabla_resultado.ColumnName = {'Puntos','error'};
            end

            % plot
            fplot(app.Grafica, Funcion)
            app.Grafica.XLim = [resultado-5;resultado+5];


            % text
            app.descripcion_result.Text = sprintf('%f es una aproximación de una raiz de f(x) con una tolerancia: %e',resultado,Error_metodo);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Bienvenida = uitab(app.TabGroup);
            app.Bienvenida.Tooltip = {''};
            app.Bienvenida.Title = 'Bienvenida';
            app.GridLayout = uigridlayout(app.Bienvenida);
            app.Titulo = uilabel(app.GridLayout);
            app.Titulo.Interpreter = 'latex';
            app.Titulo.Text = 'Metodos Numericos';
            app.Intro = uilabel(app.GridLayout);
            app.Intro.Interpreter = 'latex';
            app.Intro.Text = 'Bienvenido a nuestra app dedicada al fascinante mundo de los métodos numéricos! Sumérgete en el universo de la resolución de ecuaciones y aproximación de funciones a través de las diversas herramientas y técnicas que ofrecemos en cada uno de nuestros capítulos';
            app.descripcion_cap1 = uilabel(app.GridLayout);
            app.descripcion_cap1.Interpreter = 'latex';
            app.descripcion_cap1.Text = {'\textbf{Capítulo 1: Explorando Raíces}'; ''; 'En esta sección, exploraremos métodos como la Bisección, Punto Fijo, Regla Falsa, Newton, Secante y Raíces Múltiples. Cada uno de ellos te guiará a través de la búsqueda y determinación de las raíces de una función dada. Además, no solo te proporcionaremos los resultados, sino que también podrás observar las tablas de salida que detallan cada paso del proceso.'};
            app.descripcion_cap2 = uilabel(app.GridLayout);
            app.descripcion_cap2.Interpreter = 'latex';
            app.descripcion_cap2.Text = {'\textbf{Capítulo 2: Desentrañando Sistemas Lineales}'; ''; 'Adéntrate en los métodos de Jacobi, Gauss-Seidel y el método SOR (Successive Over-Relaxation). Descubre cómo estos enfoques resuelven sistemas de ecuaciones lineales y obtén información crucial, como el radio espectral, que te indicará la convergencia o divergencia de cada método. Aquí, nos enfocamos en la eficiencia y precisión para ofrecerte resultados confiables.'};
            app.descripcion_cap3 = uilabel(app.GridLayout);
            app.descripcion_cap3.Interpreter = 'latex';
            app.descripcion_cap3.Text = {'\textbf{Capítulo 3: Interpolación y Ajuste de Curvas}'; ''; 'Este fascinante capítulo te sumerge en el emocionante mundo de la interpolación y el ajuste de curvas. Exploraremos métodos como Vandermonde, Newton Interpolante, Lagrange y Splines Lineales y Cúbicos, que desempeñan un papel fundamental en la aproximación de funciones.'};
            app.Cap1metodosTab = uitab(app.TabGroup);
            app.Cap1metodosTab.Title = 'Cap 1 (metodos)';
            app.Grafica = uiaxes(app.Cap1metodosTab);
            title(app.Grafica, 'Funcion')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            zlabel(app.Grafica, 'Z')
            app.Grafica.XAxisLocation = 'origin';
            app.Grafica.YAxisLocation = 'origin';
            app.FxLabel = uilabel(app.Cap1metodosTab);
            app.FxLabel.Text = 'F(x) =';
            app.Entrada_Fun = uieditfield(app.Cap1metodosTab, 'text');
            app.ErrorLabel = uilabel(app.Cap1metodosTab);
            app.ErrorLabel.Text = 'Toleracia';
            app.Error = uieditfield(app.Cap1metodosTab, 'numeric');
            app.NumIteracionesLabel = uilabel(app.Cap1metodosTab);
            app.NumIteracionesLabel.Text = 'Maximo numero de iteraciones';
            app.Num_Iteraciones = uieditfield(app.Cap1metodosTab, 'numeric');
            app.TipodeerrorDropDownLabel = uilabel(app.Cap1metodosTab);
            app.TipodeerrorDropDownLabel.Text = 'Tipo de error';
            app.Control_Error = uidropdown(app.Cap1metodosTab);
            app.Control_Error.Items = {'Absoluto', 'Relativo'};
            app.Control_Error.Value = 'Absoluto';
            app.MultiplicidadSpinnerLabel = uilabel(app.Cap1metodosTab);
            app.MultiplicidadSpinnerLabel.Text = 'Multiplicidad';
            app.Multiplicidad = uispinner(app.Cap1metodosTab);
            app.Multiplicidad.Limits = [0 Inf];
            app.GxEditFieldLabel = uilabel(app.Cap1metodosTab);
            app.GxEditFieldLabel.Text = 'G(x)';
            app.Entrada_g = uieditfield(app.Cap1metodosTab, 'text');
            app.PuntoinicialEditFieldLabel = uilabel(app.Cap1metodosTab);
            app.PuntoinicialEditFieldLabel.Text = 'Punto inicial';
            app.Punto_inicial = uieditfield(app.Cap1metodosTab, 'numeric');
            app.Punto_final = uieditfield(app.Cap1metodosTab, 'numeric');
            app.PuntofinalEditFieldLabel = uilabel(app.Cap1metodosTab);
            app.PuntofinalEditFieldLabel.Text = 'Punto final';
            app.Boton_resolver = uibutton(app.Cap1metodosTab, 'push');
            app.Boton_resolver.ButtonPushedFcn = createCallbackFcn(app, @Boton_resolverButtonPushed, true);
            app.Boton_resolver.Text = 'Resolver';
            app.Tabla_resultado = uitable(app.Cap1metodosTab);
            app.Tabla_resultado.ColumnName = {'column1'; 'Column 2'; 'Column 3'; 'Column 4'; 'column 5'; 'column 6'};
            app.Tabla_resultado.RowName = {};
            app.Contenedor_metodos = uibuttongroup(app.Cap1metodosTab);
            app.Contenedor_metodos.SelectionChangedFcn = createCallbackFcn(app, @Contenedor_metodosSelectionChanged2, true);
            app.Contenedor_metodos.Title = 'Metodo';
            app.BiseccionButton = uiradiobutton(app.Contenedor_metodos);
            app.BiseccionButton.Text = 'Biseccion';
            app.BiseccionButton.Value = true;
            app.PuntoFijoButton = uiradiobutton(app.Contenedor_metodos);
            app.PuntoFijoButton.Text = 'Punto Fijo';
            app.ReglaFalsaButton = uiradiobutton(app.Contenedor_metodos);
            app.ReglaFalsaButton.Text = 'Regla Falsa';
            app.NewtonButton = uiradiobutton(app.Contenedor_metodos);
            app.NewtonButton.Text = 'Newton';
            app.SecanteButton = uiradiobutton(app.Contenedor_metodos);
            app.SecanteButton.Text = 'Secante';
            app.RaicesMultiplesButton = uiradiobutton(app.Contenedor_metodos);
            app.RaicesMultiplesButton.Text = 'Raices Multiples';
            app.RaicesMultiples2Button = uiradiobutton(app.Contenedor_metodos);
            app.RaicesMultiples2Button.Text = 'Raices Multiples 2';
            app.tab_title = uilabel(app.Cap1metodosTab);
            app.tab_title.Interpreter = 'latex';
            app.tab_title.Text = '\textbf{Capítulo 1: Explorando Raíces}';
            app.descripcion_method = uilabel(app.Cap1metodosTab);
            app.descripcion_method.Interpreter = 'latex';
            app.descripcion_method.Text = '';
            app.descripcion_result = uilabel(app.Cap1metodosTab);
            app.descripcion_result.Interpreter = 'latex';
            app.descripcion_result.Text = '';
            app.Cap2Tab = uitab(app.TabGroup);
            app.Cap2Tab.Title = 'Cap 2';
            app.Cap3Tab = uitab(app.TabGroup);
            app.Cap3Tab.Title = 'Cap 3';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.Boton_resolver.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Metodos_numericos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end