classdef Metodos_numericos_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Bienvenida                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        descripcion_cap3                matlab.ui.control.Label
        descripcion_cap2                matlab.ui.control.Label
        descripcion_cap1                matlab.ui.control.Label
        Intro                           matlab.ui.control.Label
        Titulo                          matlab.ui.control.Label
        Cap1metodosTab                  matlab.ui.container.Tab
        Solucion                        matlab.ui.control.EditField
        ElmetodoLabel                   matlab.ui.control.Label
        Contenedor_metodos              matlab.ui.container.ButtonGroup
        RaicesMultiples2Button          matlab.ui.control.RadioButton
        RaicesMultiplesButton           matlab.ui.control.RadioButton
        SecanteButton                   matlab.ui.control.RadioButton
        NewtonButton                    matlab.ui.control.RadioButton
        ReglaFalsaButton                matlab.ui.control.RadioButton
        PuntoFijoButton                 matlab.ui.control.RadioButton
        BiseccionButton                 matlab.ui.control.RadioButton
        Tabla_resultado                 matlab.ui.control.Table
        Boton_resolver                  matlab.ui.control.Button
        PuntofinalEditFieldLabel        matlab.ui.control.Label
        Punto_final                     matlab.ui.control.NumericEditField
        Punto_inicial                   matlab.ui.control.NumericEditField
        PuntoinicialEditFieldLabel      matlab.ui.control.Label
        Entrada_g                       matlab.ui.control.EditField
        GxEditFieldLabel                matlab.ui.control.Label
        Multiplicidad                   matlab.ui.control.Spinner
        MultiplicidadSpinnerLabel       matlab.ui.control.Label
        Control_Error                   matlab.ui.control.DropDown
        TipodeerrorDropDownLabel        matlab.ui.control.Label
        Num_Iteraciones                 matlab.ui.control.NumericEditField
        NumIteracionesLabel             matlab.ui.control.Label
        Error                           matlab.ui.control.NumericEditField
        ErrorLabel                      matlab.ui.control.Label
        Entrada_Fun                     matlab.ui.control.EditField
        FxLabel                         matlab.ui.control.Label
        Grafica                         matlab.ui.control.UIAxes
        Cap2Tab                         matlab.ui.container.Tab
        TablaErrores                    matlab.ui.control.Table
        ErroresLabel                    matlab.ui.control.Label
        ResultadosLabel                 matlab.ui.control.Label
        TablaResultados                 matlab.ui.control.Table
        ResolverButton                  matlab.ui.control.Button
        ErrorEditField                  matlab.ui.control.NumericEditField
        ErrorEditFieldLabel             matlab.ui.control.Label
        TipodeErrorDropDown             matlab.ui.control.DropDown
        TipodeErrorDropDownLabel        matlab.ui.control.Label
        wEditField                      matlab.ui.control.NumericEditField
        wEditFieldLabel                 matlab.ui.control.Label
        NumIteracionesEditField         matlab.ui.control.NumericEditField
        NumIteracionesEditFieldLabel    matlab.ui.control.Label
        MatrizA                         matlab.ui.control.Table
        x0Label                         matlab.ui.control.Label
        Matrizx0                        matlab.ui.control.Table
        bLabel                          matlab.ui.control.Label
        Matrizb                         matlab.ui.control.Table
        ALabel                          matlab.ui.control.Label
        Dimension                       matlab.ui.control.NumericEditField
        DimensinEditFieldLabel          matlab.ui.control.Label
        ContenedorCap2                  matlab.ui.container.ButtonGroup
        SORButton                       matlab.ui.control.RadioButton
        GaussSeidelButton               matlab.ui.control.RadioButton
        JacobiButton                    matlab.ui.control.RadioButton
        Cap3Tab                         matlab.ui.container.Tab
        Resolver_Cap_3                  matlab.ui.control.Button
        M_Puntos                        matlab.ui.control.Table
        CantidaddepuntosEditField       matlab.ui.control.NumericEditField
        CantidaddepuntosEditFieldLabel  matlab.ui.control.Label
        Contenedor_metodos_3            matlab.ui.container.ButtonGroup
        SplineDropDown                  matlab.ui.control.DropDown
        SplineButton                    matlab.ui.control.RadioButton
        LagrangeButton                  matlab.ui.control.RadioButton
        NewtonInterpolanteButton        matlab.ui.control.RadioButton
        VandermondeButton               matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ContenedorCap2, Contenedor_metodos
        function Contenedor_metodosSelectionChanged2(app, event)
            boton1 = app.Contenedor_metodos.SelectedObject.Text;
            
            switch boton1
                case 'Biseccion'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                    
                    app.Multiplicidad.Enable="off";                   
                    app.Punto_final.Enable='off';
                    app.Entrada_g.Enable='off';
                case 'Punto Fijo'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';


                    app.Multiplicidad.Enable="off";    
                    app.Punto_final.Enable='off';

                case 'Regla Falsa'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                    app.Multiplicidad.Enable="off";
                    app.Entrada_g.Enable='off';

                case 'Newton'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                    app.Multiplicidad.Enable="off";
                    app.Entrada_g.Enable="off";
                    app.Punto_final.Enable="off";

                case 'Secante'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                    app.Multiplicidad.Enable="off";
                    app.Entrada_g.Enable="off";

                case'Raices Multiples'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';
                    
                     app.Entrada_g.Enable="off";
                     app.Punto_final.Enable='off';

                    case'Raices Multiples 2'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';
                    
                    app.Entrada_g.Enable="off";
                    app.Punto_final.Enable='off';
                    app.Multiplicidad.Enable="off";
            end

            boton2 = app.ContenedorCap2.SelectedObject.Text;

            switch boton2
                case 'SOR'
                    app.MatrizA.Enable="on";
                    app.Matrizb.Enable="on";
                    app.Matrizx0.Enable="on";
                    app.Dimension.Enable="on";
                    app.NumIteracionesEditField.Enable="on";
                    app.wEditField.Enable="on";

                case 'Gauss - Seidel'
                    app.MatrizA.Enable="on";
                    app.Matrizb.Enable="on";
                    app.Matrizx0.Enable="on";
                    app.Dimension.Enable="on";
                    app.NumIteracionesEditField.Enable="on";

                    app.wEditField.Enable="off";

                case 'Jacobi'
                    app.MatrizA.Enable="on";
                    app.Matrizb.Enable="on";
                    app.Matrizx0.Enable="on";
                    app.Dimension.Enable="on";
                    app.NumIteracionesEditField.Enable="on";

                    app.wEditField.Enable="off";
            end
        end

        % Button pushed function: Boton_resolver
        function Boton_resolverButtonPushed(app, event)
            metodo=app.Contenedor_metodos.SelectedObject.Text;
            switch metodo

                case 'Biseccion'
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Punto_I=app.Punto_inicial.Value;
                    [sol,iteraciones,tabla]=Biseccion_lm(Punto_I,Error_metodo,Num_iter,Funcion);
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={"Puntos (x)","Valor de la Funcion","Error"};
                case 'Punto Fijo'
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Funcion_g=str2sym(app.Entrada_g.Value);
                    Punto_I=app.Punto_inicial.Value;
                    [tabla] = Punto_fijo(Punto_I,Error_metodo,Control_E,Num_iter,Funcion,Funcion_g);
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={"Puntos (x)","Valor de la Funcion","Error"};
                case 'Regla Falsa'
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Intervalo=[app.Punto_inicial.Value app.Punto_final.Value];
                    [X_Nuevo,tabla] = Regla_Falsa(Funcion,Intervalo,Error_metodo,Num_iter);
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={'xa','tfa','xb','tfb','txnuevo','tfn'};
                case 'Newton'
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Punto_I=app.Punto_inicial.Value;
                    [resultado,tabla] = Newton_LM(Punto_I,Error_metodo,Num_iter,Funcion,Control_E);
                    app.Tabla_resultado.Data=tabla;
                    x=-5:0.1:5;
                    y=x.^2;
                    plot(app.Grafica,x,y)
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.ColumnName={'xn','fevaluada','error'};
                case 'Secante'
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Intervalo=[app.Punto_inicial.Value app.Punto_final.Value];
                    [Resultado,tabla] = Secante(Funcion,Intervalo,Error_metodo,Control_E);
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={'Punto t','error t'};
                case'Raices Multiples'
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Punto_I=app.Punto_inicial.Value;
                    Multiplicidad_v=app.Multiplicidad.Value;
                    [Resultado,tabla]=Newton_Mult(Funcion,Punto_I,Error_metodo,Multiplicidad_v,Num_iter,Control_E)
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={'Punto t','error t'};
                    case'Raices Multiples 2'
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Punto_I=app.Punto_inicial.Value;
                    [Resultado,tabla]=Newton_mult_2(Funcion,Punto_I,Error_metodo,Num_iter,Control_E);
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={'Puntos','error'};

            end
        end

        % Callback function: Dimension, MatrizA
        function DimensionValueChanged(app, event)
            value = app.Dimension.Value;

            matriz = zeros(value,value);
            app.MatrizA.ColumnEditable = true;
            app.MatrizA.Data = matriz;

            matriz2 = zeros(value,1);
            app.Matrizb.ColumnEditable = true;
            app.Matrizb.Data = matriz2;

            app.Matrizx0.ColumnEditable = true;
            app.Matrizx0.Data = matriz2;
        end

        % Button pushed function: ResolverButton
        function ResolverButtonPushed(app, event)
            metodo=app.ContenedorCap2.SelectedObject.Text;

            switch metodo

                case 'Jacobi'
                    A = app.MatrizA.Data;
                    b = app.Matrizb.Data;
                    x0 = app.Matrizx0.Data;
                    error = app.ErrorEditField.Value;
                    Num_iter=app.NumIteracionesEditField.Value;
                    Control_E=app.TipodeErrorDropDown.Value;
                    [E,s,T] = MatJacobiSeid(x0,A,b,error,Control_E,Num_iter,0);
                    app.TablaResultados.Data=s';
                    totalIteraciones = size(E);
                    iteraciones = 1:totalIteraciones(2);
                    app.TablaErrores.Data=[E',iteraciones'];

                case 'Gauss - Seidel'
                    A = app.MatrizA.Data;
                    b = app.Matrizb.Data;
                    x0 = app.Matrizx0.Data;
                    error = app.ErrorEditField.Value;
                    Num_iter=app.NumIteracionesEditField.Value;
                    Control_E=app.TipodeErrorDropDown.Value;
                    [E,s,T] = MatJacobiSeid(x0,A,b,error,Control_E,Num_iter,1);
                    app.TablaResultados.Data=s';
                    totalIteraciones = size(E);
                    iteraciones = 1:totalIteraciones(2);
                    app.TablaErrores.Data=[E',iteraciones'];

                case 'SOR'
                    A = app.MatrizA.Data;
                    b = app.Matrizb.Data;
                    x0 = app.Matrizx0.Data;
                    error = app.ErrorEditField.Value;
                    Num_iter=app.NumIteracionesEditField.Value;
                    Control_E=app.TipodeErrorDropDown.Value;
                    w = app.wEditField.Value;
                    [E, s] = SOR(x0, A, b, error, Num_iter, w, Control_E);
                    app.TablaResultados.Data=s';
                    totalIteraciones = size(E);
                    iteraciones = 1:totalIteraciones(2);
                    app.TablaErrores.Data=[E',iteraciones'];
            end
        end

        % Value changed function: CantidaddepuntosEditField
        function CantidaddepuntosEditFieldValueChanged(app, event)
            value = app.CantidaddepuntosEditField.Value;
            Lista_Puntos=zeros(2,value);
            app.M_Puntos.ColumnEditable = true;
            app.M_Puntos.RowName={'X','Y'};
            app.M_Puntos.Data = Lista_Puntos;
            
        end

        % Selection changed function: Contenedor_metodos_3
        function Contenedor_metodos_3SelectionChanged(app, event)
            boton_3 =  convertCharsToStrings(app.Contenedor_metodos_3.SelectedObject.Text);
            if boton_3=="Spline"
                app.SplineDropDown.Enable='on';
            else
                app.SplineDropDown.Enable='off';
            end
            
        end

        % Button pushed function: Resolver_Cap_3
        function Resolver_Cap_3Pushed(app, event)
            metodo_3=app.Contenedor_metodos_3.SelectedObject.Text;

            switch metodo_3
                case 'Vandermonde'
                    Puntos_x=app.M_Puntos.Data(1,:);
                    Puntos_y=app.M_Puntos.Data(1,:);
                    D = Vandermonde(x, y, show);
                case 'Newton Interpolante'
                case 'Lagrange'
                    [pol] = Lagrange(x,y);
                case 'Spline'
                    [Tabla] = Spline(x,y,d);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Bienvenida = uitab(app.TabGroup);
            app.Bienvenida.Tooltip = {''};
            app.Bienvenida.Title = 'Bienvenida';
            app.GridLayout = uigridlayout(app.Bienvenida);
            app.Titulo = uilabel(app.GridLayout);
            app.Titulo.Text = 'Metodos Numericos';
            app.Intro = uilabel(app.GridLayout);
            app.Intro.Text = 'Bienvenido a nuestra app dedicada al fascinante mundo de los métodos numéricos! Sumérgete en el universo de la resolución de ecuaciones y aproximación de funciones a través de las diversas herramientas y técnicas que ofrecemos en cada uno de nuestros capítulos';
            app.descripcion_cap1 = uilabel(app.GridLayout);
            app.descripcion_cap1.Interpreter = 'latex';
            app.descripcion_cap1.Text = {'\textbf{Capítulo 1: Explorando Raíces}'; ''; 'En esta sección, exploraremos métodos como la Bisección, Punto Fijo, Regla Falsa, Newton, Secante y Raíces Múltiples. Cada uno de ellos te guiará a través de la búsqueda y determinación de las raíces de una función dada. Además, no solo te proporcionaremos los resultados, sino que también podrás observar las tablas de salida que detallan cada paso del proceso.'};
            app.descripcion_cap2 = uilabel(app.GridLayout);
            app.descripcion_cap2.Interpreter = 'latex';
            app.descripcion_cap2.Text = {'\textbf{Capítulo 2: Desentrañando Sistemas Lineales}'; ''; 'Adéntrate en los métodos de Jacobi, Gauss-Seidel y el método SOR (Successive Over-Relaxation). Descubre cómo estos enfoques resuelven sistemas de ecuaciones lineales y obtén información crucial, como el radio espectral, que te indicará la convergencia o divergencia de cada método. Aquí, nos enfocamos en la eficiencia y precisión para ofrecerte resultados confiables.'};
            app.descripcion_cap3 = uilabel(app.GridLayout);
            app.descripcion_cap3.Interpreter = 'latex';
            app.descripcion_cap3.Text = {'\textbf{Capítulo 3: Interpolación y Ajuste de Curvas}'; ''; 'Este fascinante capítulo te sumerge en el emocionante mundo de la interpolación y el ajuste de curvas. Exploraremos métodos como Vandermonde, Newton Interpolante, Lagrange y Splines Lineales y Cúbicos, que desempeñan un papel fundamental en la aproximación de funciones.'};
            app.Cap1metodosTab = uitab(app.TabGroup);
            app.Cap1metodosTab.Title = 'Cap 1 (metodos)';
            app.Grafica = uiaxes(app.Cap1metodosTab);
            title(app.Grafica, 'Funcion')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            zlabel(app.Grafica, 'Z')
            app.Grafica.XAxisLocation = 'origin';
            app.Grafica.YAxisLocation = 'origin';
            app.FxLabel = uilabel(app.Cap1metodosTab);
            app.FxLabel.Text = 'F(x) =';
            app.Entrada_Fun = uieditfield(app.Cap1metodosTab, 'text');
            app.ErrorLabel = uilabel(app.Cap1metodosTab);
            app.ErrorLabel.Text = 'Error =';
            app.Error = uieditfield(app.Cap1metodosTab, 'numeric');
            app.NumIteracionesLabel = uilabel(app.Cap1metodosTab);
            app.NumIteracionesLabel.Text = 'Num Iteraciones = ';
            app.Num_Iteraciones = uieditfield(app.Cap1metodosTab, 'numeric');
            app.TipodeerrorDropDownLabel = uilabel(app.Cap1metodosTab);
            app.TipodeerrorDropDownLabel.Text = 'Tipo de error';
            app.Control_Error = uidropdown(app.Cap1metodosTab);
            app.Control_Error.Items = {'Absoluto', 'Relativo'};
            app.Control_Error.Value = 'Absoluto';
            app.MultiplicidadSpinnerLabel = uilabel(app.Cap1metodosTab);
            app.MultiplicidadSpinnerLabel.Text = 'Multiplicidad';
            app.Multiplicidad = uispinner(app.Cap1metodosTab);
            app.Multiplicidad.Limits = [0 Inf];
            app.GxEditFieldLabel = uilabel(app.Cap1metodosTab);
            app.GxEditFieldLabel.Text = 'G(x)';
            app.Entrada_g = uieditfield(app.Cap1metodosTab, 'text');
            app.PuntoinicialEditFieldLabel = uilabel(app.Cap1metodosTab);
            app.PuntoinicialEditFieldLabel.Text = 'Punto inicial';
            app.Punto_inicial = uieditfield(app.Cap1metodosTab, 'numeric');
            app.Punto_final = uieditfield(app.Cap1metodosTab, 'numeric');
            app.PuntofinalEditFieldLabel = uilabel(app.Cap1metodosTab);
            app.PuntofinalEditFieldLabel.Text = 'Punto final';
            app.Boton_resolver = uibutton(app.Cap1metodosTab, 'push');
            app.Boton_resolver.ButtonPushedFcn = createCallbackFcn(app, @Boton_resolverButtonPushed, true);
            app.Boton_resolver.Text = 'Resolver';
            app.Tabla_resultado = uitable(app.Cap1metodosTab);
            app.Tabla_resultado.ColumnName = {'column1'; 'Column 2'; 'Column 3'; 'Column 4'; 'column 5'; 'column 6'};
            app.Tabla_resultado.RowName = {};
            app.Contenedor_metodos = uibuttongroup(app.Cap1metodosTab);
            app.Contenedor_metodos.SelectionChangedFcn = createCallbackFcn(app, @Contenedor_metodosSelectionChanged2, true);
            app.Contenedor_metodos.Title = 'Metodo';
            app.BiseccionButton = uiradiobutton(app.Contenedor_metodos);
            app.BiseccionButton.Text = 'Biseccion';
            app.BiseccionButton.Value = true;
            app.PuntoFijoButton = uiradiobutton(app.Contenedor_metodos);
            app.PuntoFijoButton.Text = 'Punto Fijo';
            app.ReglaFalsaButton = uiradiobutton(app.Contenedor_metodos);
            app.ReglaFalsaButton.Text = 'Regla Falsa';
            app.NewtonButton = uiradiobutton(app.Contenedor_metodos);
            app.NewtonButton.Text = 'Newton';
            app.SecanteButton = uiradiobutton(app.Contenedor_metodos);
            app.SecanteButton.Text = 'Secante';
            app.RaicesMultiplesButton = uiradiobutton(app.Contenedor_metodos);
            app.RaicesMultiplesButton.Text = 'Raices Multiples';
            app.RaicesMultiples2Button = uiradiobutton(app.Contenedor_metodos);
            app.RaicesMultiples2Button.Text = 'Raices Multiples 2';
            app.ElmetodoLabel = uilabel(app.Cap1metodosTab);
            app.ElmetodoLabel.Text = 'El metodo';
            app.Solucion = uieditfield(app.Cap1metodosTab, 'text');
            app.Cap2Tab = uitab(app.TabGroup);
            app.Cap2Tab.Title = 'Cap 2';
            app.ContenedorCap2 = uibuttongroup(app.Cap2Tab);
            app.ContenedorCap2.SelectionChangedFcn = createCallbackFcn(app, @Contenedor_metodosSelectionChanged2, true);
            app.ContenedorCap2.Title = 'Metodo';
            app.JacobiButton = uiradiobutton(app.ContenedorCap2);
            app.JacobiButton.Text = 'Jacobi';
            app.JacobiButton.Value = true;
            app.GaussSeidelButton = uiradiobutton(app.ContenedorCap2);
            app.GaussSeidelButton.Text = 'Gauss - Seidel';
            app.SORButton = uiradiobutton(app.ContenedorCap2);
            app.SORButton.Text = 'SOR';
            app.DimensinEditFieldLabel = uilabel(app.Cap2Tab);
            app.DimensinEditFieldLabel.Text = 'Dimensión';
            app.Dimension = uieditfield(app.Cap2Tab, 'numeric');
            app.Dimension.ValueChangedFcn = createCallbackFcn(app, @DimensionValueChanged, true);
            app.ALabel = uilabel(app.Cap2Tab);
            app.ALabel.Text = 'A =';
            app.Matrizb = uitable(app.Cap2Tab);
            app.Matrizb.ColumnName = '';
            app.Matrizb.RowName = {};
            app.bLabel = uilabel(app.Cap2Tab);
            app.bLabel.Text = 'b =';
            app.Matrizx0 = uitable(app.Cap2Tab);
            app.Matrizx0.ColumnName = '';
            app.Matrizx0.RowName = {};
            app.x0Label = uilabel(app.Cap2Tab);
            app.x0Label.Text = 'x0 =';
            app.MatrizA = uitable(app.Cap2Tab);
            app.MatrizA.ColumnName = '';
            app.MatrizA.RowName = {};
            app.MatrizA.CellEditCallback = createCallbackFcn(app, @DimensionValueChanged, true);
            app.NumIteracionesEditFieldLabel = uilabel(app.Cap2Tab);
            app.NumIteracionesEditFieldLabel.Text = 'Num Iteraciones';
            app.NumIteracionesEditField = uieditfield(app.Cap2Tab, 'numeric');
            app.wEditFieldLabel = uilabel(app.Cap2Tab);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.Cap2Tab, 'numeric');
            app.TipodeErrorDropDownLabel = uilabel(app.Cap2Tab);
            app.TipodeErrorDropDownLabel.Text = 'Tipo de Error';
            app.TipodeErrorDropDown = uidropdown(app.Cap2Tab);
            app.TipodeErrorDropDown.Items = {'Absoluto', 'Relativo'};
            app.TipodeErrorDropDown.Value = 'Absoluto';
            app.ErrorEditFieldLabel = uilabel(app.Cap2Tab);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.Cap2Tab, 'numeric');
            app.ResolverButton = uibutton(app.Cap2Tab, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @ResolverButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.TablaResultados = uitable(app.Cap2Tab);
            app.TablaResultados.ColumnName = '';
            app.TablaResultados.RowName = {};
            app.ResultadosLabel = uilabel(app.Cap2Tab);
            app.ResultadosLabel.Text = 'Resultados';
            app.ErroresLabel = uilabel(app.Cap2Tab);
            app.ErroresLabel.Text = 'Errores';
            app.TablaErrores = uitable(app.Cap2Tab);
            app.TablaErrores.ColumnName = '';
            app.TablaErrores.RowName = {};
            app.Cap3Tab = uitab(app.TabGroup);
            app.Cap3Tab.Title = 'Cap 3';
            app.UIAxes = uiaxes(app.Cap3Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Contenedor_metodos_3 = uibuttongroup(app.Cap3Tab);
            app.Contenedor_metodos_3.SelectionChangedFcn = createCallbackFcn(app, @Contenedor_metodos_3SelectionChanged, true);
            app.Contenedor_metodos_3.Title = 'Metodo';
            app.VandermondeButton = uiradiobutton(app.Contenedor_metodos_3);
            app.VandermondeButton.Text = 'Vandermonde';
            app.VandermondeButton.Value = true;
            app.NewtonInterpolanteButton = uiradiobutton(app.Contenedor_metodos_3);
            app.NewtonInterpolanteButton.Text = 'Newton Interpolante';
            app.LagrangeButton = uiradiobutton(app.Contenedor_metodos_3);
            app.LagrangeButton.Text = 'Lagrange';
            app.SplineButton = uiradiobutton(app.Contenedor_metodos_3);
            app.SplineButton.Text = 'Spline';
            app.SplineDropDown = uidropdown(app.Contenedor_metodos_3);
            app.SplineDropDown.Items = {'Lineal', 'Cubico'};
            app.SplineDropDown.Enable = 'off';
            app.SplineDropDown.Value = 'Lineal';
            app.CantidaddepuntosEditFieldLabel = uilabel(app.Cap3Tab);
            app.CantidaddepuntosEditFieldLabel.Text = 'Cantidad de puntos';
            app.CantidaddepuntosEditField = uieditfield(app.Cap3Tab, 'numeric');
            app.CantidaddepuntosEditField.ValueChangedFcn = createCallbackFcn(app, @CantidaddepuntosEditFieldValueChanged, true);
            app.M_Puntos = uitable(app.Cap3Tab);
            app.M_Puntos.ColumnName = '';
            app.M_Puntos.RowName = {};
            app.Resolver_Cap_3 = uibutton(app.Cap3Tab, 'push');
            app.Resolver_Cap_3.ButtonPushedFcn = createCallbackFcn(app, @Resolver_Cap_3Pushed, true);
            app.Resolver_Cap_3.Text = 'Resolver';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.Boton_resolver.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Metodos_numericos_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end