classdef Metodos_numericos_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Bienvenida                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        descripcion_cap3                matlab.ui.control.Label
        descripcion_cap2                matlab.ui.control.Label
        descripcion_cap1                matlab.ui.control.Label
        Intro                           matlab.ui.control.Label
        Titulo                          matlab.ui.control.Label
        Cap1Tab                         matlab.ui.container.Tab
        descripcion_result              matlab.ui.control.Label
        descripcion_method              matlab.ui.control.Label
        tab_title                       matlab.ui.control.Label
        Contenedor_metodos              matlab.ui.container.ButtonGroup
        RaicesMultiples2Button          matlab.ui.control.RadioButton
        RaicesMultiplesButton           matlab.ui.control.RadioButton
        SecanteButton                   matlab.ui.control.RadioButton
        NewtonButton                    matlab.ui.control.RadioButton
        ReglaFalsaButton                matlab.ui.control.RadioButton
        PuntoFijoButton                 matlab.ui.control.RadioButton
        BiseccionButton                 matlab.ui.control.RadioButton
        Tabla_resultado                 matlab.ui.control.Table
        Boton_resolver                  matlab.ui.control.Button
        PuntofinalEditFieldLabel_2      matlab.ui.control.Label
        Punto_final                     matlab.ui.control.NumericEditField
        Punto_inicial                   matlab.ui.control.NumericEditField
        PuntoinicialEditFieldLabel_2    matlab.ui.control.Label
        Entrada_g                       matlab.ui.control.EditField
        GxEditFieldLabel_2              matlab.ui.control.Label
        Multiplicidad                   matlab.ui.control.Spinner
        MultiplicidadSpinnerLabel_2     matlab.ui.control.Label
        Control_Error                   matlab.ui.control.DropDown
        TipodeerrorDropDownLabel_2      matlab.ui.control.Label
        Num_Iteraciones                 matlab.ui.control.NumericEditField
        NumIteracionesLabel_2           matlab.ui.control.Label
        Error                           matlab.ui.control.NumericEditField
        ErrorLabel_2                    matlab.ui.control.Label
        Entrada_Fun                     matlab.ui.control.EditField
        FxLabel_2                       matlab.ui.control.Label
        Grafica                         matlab.ui.control.UIAxes
        Cap2Tab                         matlab.ui.container.Tab
        descripcion_result_2            matlab.ui.control.Label
        descripcion_method_2            matlab.ui.control.Label
        tab_title_2                     matlab.ui.control.Label
        TablaErrores                    matlab.ui.control.Table
        ErroresLabel                    matlab.ui.control.Label
        ResultadosLabel                 matlab.ui.control.Label
        TablaResultados                 matlab.ui.control.Table
        ResolverButton                  matlab.ui.control.Button
        ErrorEditField                  matlab.ui.control.NumericEditField
        ErrorEditFieldLabel             matlab.ui.control.Label
        TipodeErrorDropDown             matlab.ui.control.DropDown
        TipodeErrorDropDownLabel        matlab.ui.control.Label
        wEditField                      matlab.ui.control.NumericEditField
        wEditFieldLabel                 matlab.ui.control.Label
        NumIteracionesEditField         matlab.ui.control.NumericEditField
        NumIteracionesEditFieldLabel    matlab.ui.control.Label
        MatrizA                         matlab.ui.control.Table
        x_0Label                        matlab.ui.control.Label
        Matrizx0                        matlab.ui.control.Table
        bLabel                          matlab.ui.control.Label
        Matrizb                         matlab.ui.control.Table
        ALabel                          matlab.ui.control.Label
        Dimension                       matlab.ui.control.NumericEditField
        DimensinEditFieldLabel          matlab.ui.control.Label
        ContenedorCap2                  matlab.ui.container.ButtonGroup
        SORButton                       matlab.ui.control.RadioButton
        GaussSeidelButton               matlab.ui.control.RadioButton
        JacobiButton                    matlab.ui.control.RadioButton
        Cap3Tab                         matlab.ui.container.Tab
        method_result_3                 matlab.ui.control.Label
        text_4_2                        matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xLabel                          matlab.ui.control.Label
        descripcion_method_3            matlab.ui.control.Label
        tab_title_3                     matlab.ui.control.Label
        Resolver_Cap_3                  matlab.ui.control.Button
        M_Puntos                        matlab.ui.control.Table
        CantidaddepuntosEditField       matlab.ui.control.NumericEditField
        CantidaddepuntosEditFieldLabel  matlab.ui.control.Label
        Contenedor_metodos_3            matlab.ui.container.ButtonGroup
        SplineDropDown                  matlab.ui.control.DropDown
        SplineButton                    matlab.ui.control.RadioButton
        LagrangeButton                  matlab.ui.control.RadioButton
        NewtonInterpolanteButton        matlab.ui.control.RadioButton
        VandermondeButton               matlab.ui.control.RadioButton
        Grafica_3                       matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
    end

    
    properties (Access = public)
        Funcion_cap3 % Description
        Funcion_spline
        oldpaht1 = addpath(genpath('ANALISIS_proyecto/funciones_cap'))
        oldpaht2 = addpath(genpath('funciones_cap'))
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: Contenedor_metodos
        function Contenedor_metodos_1SelectionChanged(app, event)
            boton1 = app.Contenedor_metodos.SelectedObject.Text;
            
            switch boton1
                case 'Biseccion'
           
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                
                    app.Multiplicidad.Enable="off";                   
                    app.Entrada_g.Enable='off';

                    app.descripcion_method.Text = sprintf([boton1 ...
                        '\n - función continua' ...
                        '\n - $[a, b]$, con la condición $f (a) * f (b) < 0$' ...
                        '\n - la raíz $x_v$, es contienidad en $[a, b]$' ...
                        '\n - La sucesión $x_{n}$ generada por ' ...
                        'la aplicación del método cumple que para todo $n > 0$' ...
                        '\n |x_v − x_{n}| < (b - a)/2^n$']);
                    
                case 'Punto Fijo'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';


                    app.Multiplicidad.Enable="off";    
                    app.Punto_final.Enable='off';

                    app.descripcion_method.Text = sprintf([boton1 ...
                        '\n - Si g es una función continua en el intervalo [a, b] ' ...
                        'y x esta en $[a, b]$ si g(x) esta en [a, b] ' ...
                        'entonces g tiene un punto fijo en [a, b]. \n - para todo ' ...
                        'x en (a, b), g′(x) existe y $|g′(x)| ≤ k < 1$, entonces' ...
                        ' g tiene un único punto fijo p en [a, b].']);

                case 'Regla Falsa'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                    app.Multiplicidad.Enable="off";
                    app.Entrada_g.Enable='off';

                    app.descripcion_method.Text = sprintf([boton1 ...
                        '\n - Si g es una función continua en el intervalo [a, b] ' ...
                        'y x esta en $[a, b]$ si g(x) esta en [a, b] ' ...
                        'entonces g tiene un punto fijo en [a, b]. \n - para todo ' ...
                        'x en (a, b), g′(x) existe y $|g′(x)| ≤ k < 1$, entonces' ...
                        ' g tiene un único punto fijo p en [a, b].']);

                case 'Newton'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                    app.Multiplicidad.Enable="off";
                    app.Entrada_g.Enable="off";
                    app.Punto_final.Enable="off";

                    app.descripcion_method.Text = sprintf([boton1 ...
                        '\n f es continua y tiene primera derivada en [a, b] ' ...
                        'con la raíz $x_v$ en [a, b] tal que $f (x_v) = 0$' ...
                        'y $f^{(1)}(x_v)$ diferente de cero existe $d > 0$' ...
                        'tal que el método converge a $x_v$ para cualquier ' ...
                        'valor inicial $x_0$ en $[x_v − d, x_v + d]$']);

                case 'Secante'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';

                    app.Multiplicidad.Enable="off";
                    app.Entrada_g.Enable="off";

                    app.descripcion_method.Text = sprintf(boton1);

                case'Raices Multiples'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';
                    
                     app.Entrada_g.Enable="off";
                     app.Punto_final.Enable='off';

                     app.descripcion_method.Text = sprintf(boton1);

                case'Raices Multiples 2'
                    app.Entrada_Fun.Enable="on";
                    app.Error.Enable="on";
                    app.Num_Iteraciones.Enable="on";
                    app.Control_Error.Enable="on";
                    app.Multiplicidad.Enable="on";
                    app.Entrada_g.Enable='on';
                    app.Punto_inicial.Enable='on';
                    app.Punto_final.Enable='on';
                    
                    app.Entrada_g.Enable="off";
                    app.Punto_final.Enable='off';
                    app.Multiplicidad.Enable="off";

                    app.descripcion_method.Text = sprintf(boton1);
            end
            
            title(app.Grafica, boton1);
        end

        % Button pushed function: Boton_resolver
        function Resolver_Cap_1_ButtonPushed(app, event)
            metodo = app.Contenedor_metodos.SelectedObject.Text;

            switch metodo

                case 'Biseccion'
                    % setup
                    Funcion = str2sym(app.Entrada_Fun.Value);
                    Error_metodo = app.Error.Value;
                    Num_iter = app.Num_Iteraciones.Value;
                    Control_E = app.Control_Error.Value;
                    Punto_I = app.Punto_inicial.Value;
                    Punto_f=app.Punto_final.Value;

                    % metodo
                    [resultado, tabla, output] = Biseccion(Punto_I,Punto_f,Error_metodo,Num_iter,Funcion);

                    % tabla
                    app.Tabla_resultado.RowName = 'numbered';
                    app.Tabla_resultado.Data = tabla;
                    app.Tabla_resultado.ColumnName = {"x_n","f(x_n)","Error"};

                case 'Punto Fijo'

                    % setup
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Funcion_g=str2sym(app.Entrada_g.Value);
                    Punto_I=app.Punto_inicial.Value;

                    % method
                    [resultado, tabla, output] = Punto_fijo(Punto_I,Error_metodo,Control_E,Num_iter,Funcion,Funcion_g);

                    % table
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={"Puntos (x)","Valor de la Funcion","Error"};

                case 'Regla Falsa'
                    % setup
                    Funcion = str2sym(app.Entrada_Fun.Value);
                    Error_metodo = app.Error.Value;
                    Num_iter = app.Num_Iteraciones.Value;
                    Control_E = app.Control_Error.Value; % tipo error
                    Intervalo = [app.Punto_inicial.Value app.Punto_final.Value];

                    % method
                    [resultado, tabla, output] = Regla_Falsa(Funcion,Intervalo,Error_metodo,Num_iter);

                    % table
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={'xa','tfa','xb','tfb','txnuevo','tfn'};

                case 'Newton'
                    % setup
                    Funcion = str2sym(app.Entrada_Fun.Value);
                    Error_metodo = app.Error.Value;
                    Num_iter = app.Num_Iteraciones.Value;
                    Control_E = app.Control_Error.Value;
                    Punto_I = app.Punto_inicial.Value;

                    % method
                    [resultado, tabla, output] = Newton_LM(Punto_I,Error_metodo,Num_iter,Funcion,Control_E);

                    % table
                    app.Tabla_resultado.Data = tabla;
                    app.Tabla_resultado.RowName = 'numbered';
                    app.Tabla_resultado.ColumnName = {'x_n','f(x_n)','error'};
                    
                case 'Secante'
                    % setup
                    Funcion = str2sym(app.Entrada_Fun.Value);
                    Error_metodo = app.Error.Value;
                    Num_iter = app.Num_Iteraciones.Value;
                    Control_E = app.Control_Error.Value;
                    Intervalo = [app.Punto_inicial.Value app.Punto_final.Value];

                    % method
                    [resultado, tabla, output] = Secante(Funcion,Intervalo,Error_metodo,Control_E);

                    % table
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data = tabla;
                    app.Tabla_resultado.ColumnName={'Punto t','error t'};

                case'Raices Multiples'
                    % setup
                    Funcion=str2sym(app.Entrada_Fun.Value);
                    Error_metodo=app.Error.Value;
                    Num_iter=app.Num_Iteraciones.Value;
                    Control_E=app.Control_Error.Value;
                    Punto_I=app.Punto_inicial.Value;
                    Multiplicidad_v=app.Multiplicidad.Value;

                    % method
                    [resultado, tabla, output] = Newton_Mult(Funcion,Punto_I,Error_metodo,Multiplicidad_v,Num_iter,Control_E);

                    % result
                    app.Tabla_resultado.RowName='numbered';
                    app.Tabla_resultado.Data=tabla;
                    app.Tabla_resultado.ColumnName={'Punto t','error t'};

                case'Raices Multiples 2'
                    % setup
                    Funcion = str2sym(app.Entrada_Fun.Value);
                    Error_metodo = app.Error.Value;
                    Num_iter = app.Num_Iteraciones.Value;
                    Control_E = app.Control_Error.Value;
                    Punto_I = app.Punto_inicial.Value;

                    % method
                    [resultado, tabla, output] = Newton_mult_2(Funcion,Punto_I,Error_metodo,Num_iter,Control_E);

                    % table
                    app.Tabla_resultado.RowName = 'numbered';
                    app.Tabla_resultado.Data = tabla;
                    app.Tabla_resultado.ColumnName = {'Puntos','error'};
            end

            % plot
            fplot(app.Grafica, Funcion)
            app.Grafica.XLim = [resultado-5;resultado+5];


           % text 
           app.descripcion_result.Text = output;
        end

        % Selection changed function: ContenedorCap2
        function Contenedor_metodos_Cap2_SelectionChanged(app, event)
            boton2 = app.ContenedorCap2.SelectedObject.Text;

            switch boton2
                case 'SOR'
                    app.MatrizA.Enable="on";
                    app.Matrizb.Enable="on";
                    app.Matrizx0.Enable="on";
                    app.Dimension.Enable="on";
                    app.NumIteracionesEditField.Enable="on";
                    app.wEditField.Enable="on";
                    
                    app.descripcion_method_2.Text = sprintf([boton2 ...
                        '\n - Si A es una matriz simétrica definida ' ...
                        'positiva $0 < w < 2$, entonces el método de ' ...
                        'relajación converge para cualquier elección ' ...
                        'de lavalor inicial $x_0$']);


                case 'Gauss - Seidel'
                    app.MatrizA.Enable="on";
                    app.Matrizb.Enable="on";
                    app.Matrizx0.Enable="on";
                    app.Dimension.Enable="on";
                    app.NumIteracionesEditField.Enable="on";

                    app.wEditField.Enable="off";

                   app.descripcion_method_2.Text = sprintf([boton2 ...
                        '\n - Si A es estrictamente diagonalmente dominante,' ...
                        ' entonces tanto elmétodo iterativo de Jacobi como ' ...
                        'el de Gauss-Seidel convergen a la única solución ' ...
                        'del sistema para cualquier valor inicial $x_0$']);

                case 'Jacobi'
                    app.MatrizA.Enable="on";
                    app.Matrizb.Enable="on";
                    app.Matrizx0.Enable="on";
                    app.Dimension.Enable="on";
                    app.NumIteracionesEditField.Enable="on";

                    app.wEditField.Enable="off";

                    app.descripcion_method_2.Text = sprintf([boton2 ...
                        '\n - Si A es estrictamente diagonalmente dominante,' ...
                        ' entonces tanto elmétodo iterativo de Jacobi como ' ...
                        'el de Gauss-Seidel convergen a la única solución ' ...
                        'del sistema para cualquier valor inicial $x_0$']);
            end
        end

        % Button pushed function: ResolverButton
        function Resolver_Cap_2_ButtonPushed(app, event)
            metodo = app.ContenedorCap2.SelectedObject.Text;

            switch metodo

                case 'Jacobi'
                    A = app.MatrizA.Data;
                    b = app.Matrizb.Data;
                    x0 = app.Matrizx0.Data;
                    error = app.ErrorEditField.Value;
                    Num_iter=app.NumIteracionesEditField.Value;
                    Control_E=app.TipodeErrorDropDown.Value;

                    [E, s, T, output] = MatJacobiSeid(x0,A,b,error,Control_E,Num_iter,0);

                    app.TablaResultados.Data=s';
                    totalIteraciones = size(E);
                    iteraciones = 1:totalIteraciones(2);
                    app.TablaErrores.Data=[E',iteraciones'];

                case 'Gauss - Seidel'
                    A = app.MatrizA.Data;
                    b = app.Matrizb.Data;
                    x0 = app.Matrizx0.Data;
                    error = app.ErrorEditField.Value;
                    Num_iter=app.NumIteracionesEditField.Value;
                    Control_E=app.TipodeErrorDropDown.Value;

                    [E, s, T, output] = MatJacobiSeid(x0,A,b,error,Control_E,Num_iter,1);

                    app.TablaResultados.Data=s';
                    totalIteraciones = size(E);
                    iteraciones = 1:totalIteraciones(2);
                    app.TablaErrores.Data=[E',iteraciones'];

                case 'SOR'
                    A = app.MatrizA.Data;
                    b = app.Matrizb.Data;
                    x0 = app.Matrizx0.Data;
                    error = app.ErrorEditField.Value;
                    Num_iter=app.NumIteracionesEditField.Value;
                    Control_E=app.TipodeErrorDropDown.Value;
                    w = app.wEditField.Value;

                    [E, s, output] = SOR(x0, A, b, error, Num_iter, w, Control_E);

                    app.TablaResultados.Data=s';
                    totalIteraciones = size(E);
                    iteraciones = 1:totalIteraciones(2);
                    app.TablaErrores.Data=[E',iteraciones'];    
            end

            % text 
            app.descripcion_result_2.Text = output;
        end

        % Value changed function: Dimension
        function DimensionValueChanged(app, event)
            value = app.Dimension.Value;

            matriz = zeros(value,value);
            app.MatrizA.ColumnEditable = true;
            app.MatrizA.Data = matriz;

            matriz2 = zeros(value,1);
            app.Matrizb.ColumnEditable = true;
            app.Matrizb.Data = matriz2;

            app.Matrizx0.ColumnEditable = true;
            app.Matrizx0.Data = matriz2;
        end

        % Callback function: Contenedor_metodos_3, SplineDropDown
        function Contenedor_metodos_3SelectionChanged(app, event)
            boton_3 =  convertCharsToStrings(app.Contenedor_metodos_3.SelectedObject.Text);
            app.CantidaddepuntosEditField.Enable = "on";

        switch boton_3
            case 'Lagrange'
                
                app.SplineDropDown.Enable='off';
               
                 app.descripcion_method_3.Text = [boton_3 
                     '- Dados n + 1 puntos (x0, y0), (x1, y1), (x2, y2), ' ...
                     '..., (xn, yn), hay un unico polinomio p(x) de grado ' ...
                     'máximo n que satisface p(xi) = f(xi) para todos los ' ...
                     'i = 0, 1, 2,..., n'];

            case 'Newton Interpolante'

                app.SplineDropDown.Enable='off';


                app.descripcion_method_3.Text = [boton_3 ...
                    '- Dados n + 1 puntos distintos (x0, y0), (x1, y1)' ...
                    ' (x2, y2),..., (xn, yn), donde cada xi está en el ' ...
                    'intervalo [a, b], existe un único polinomio p(x) ' ...
                    'de grado máximo n que satisface p(xi) = f(xi). ' ...
                    'Esta formulación puede expresarse como:' ...
                    'pn(x) = f[x0] + f[x0, x1](x - x0) + f[x0, x1, x2]' ...
                    '(x - x0)(x - x1) + ... +f[x0, x1, x2, ..., xn]' ...
                    '(x - x0)(x - x1)...(x - xn-1)'];

            case 'Vandermonde'

                app.SplineDropDown.Enable='off';

                app.descripcion_method_3.Text = [boton_3 ...
                    "- Cada x tiene un único valor de y", ...
                    "- El sistema de ecuaciones siempre se puede resolver " ...
                    "porque la matriz de Vandermonde es no singular" ...
                    "- La matriz de Vandermonde es inestable cuando los " ...
                    "valores de x están muy cercanos o hay muchos puntos"];

            case 'Spline'
                app.SplineDropDown.Enable='on';

                drop_down = app.SplineDropDown.Value;
                switch drop_down
                    case 'Lineal'
                        app.descripcion_method_3.Text = sprintf(['%s %s' ...
                            '\n Un spline lineal interpolante p para una ' ...
                            'función f en el intervalo [a, b] con nodos ' ...
                            'a = x0 < x1 < · · · < xn = b, satisface ' ...
                            'estas condiciones:' ...
                            '\n - p(x) es un polinomio lineal, pj(x), en ' ...
                            'cada intervalo [xj−1, xj] para j = 1, . . . , n' ...
                            '\n - pj(xj−1) = f(xj−1) y pj(xj) = f(xj) para ' ...
                            'cada j = 1, . . . , n' ...
                            '\n - pj+1(xj) = pj(xj) para cada j = 1, . . . ' ...
                            ', n − 1.'],boton_3, drop_down);
                    case 'Cubico'

                        app.descripcion_method_3.Text = sprintf(['%s %s', ...
                            '\n Un spline cúbico interpolante p para una ' ...
                            'función f en el intervalo [a, b], con nodos' ...
                            ' a = x0 < x1 < · · · < xn = b, cumple con' ...
                            ' las siguientes condiciones:' ...
                            '\n - p(x) es un polinomio cúbico, pj(x), en ' ...
                            'cada intervalo [xj−1, xj] para j = 1, . . . , n' ...
                            '\n - pj(xj−1) = f(xj−1) y pj(xj) = f(xj) para ' ...
                            'cada j = 1, . . . , n' ...
                            '\n - pj+1(xj) = pj(xj) para cada j = 1, . . .' ...
                            ' , n − 1.' ...
                            '\n - La primera derivada de pj+1 en xj es ' ...
                            'igual a la primera derivada de pj en xj, ' ...
                            'para cada j = 1, . . . , n − 1' ...
                            '\n - La segunda derivada de pj+1 en xj es ' ...
                            'igual a la segunda derivada de pj en xj, ' ...
                            'para cada j = 1, . . . , n − 1' ...
                            '\n - La segunda derivada de p en x1 es 0 y ' ...
                            'la segunda derivada de p en xn es 0'],boton_3, drop_down);
                end
                

        end
        

        end

        % Value changed function: CantidaddepuntosEditField
        function CantidaddepuntosEditFieldValueChanged(app, event)
            value = app.CantidaddepuntosEditField.Value;

            app.Resolver_Cap_3.Enable = 'on';
            app.M_Puntos.Enable = "on";
            app.M_Puntos.Visible = "on";

            Lista_Puntos=zeros(2,value);
            app.M_Puntos.ColumnEditable = true;
            app.M_Puntos.RowName={'X','Y'};
            app.M_Puntos.Data = Lista_Puntos;
            
        end

        % Button pushed function: Resolver_Cap_3
        function Resolver_Cap_3Pushed(app, event)
            metodo_3=app.Contenedor_metodos_3.SelectedObject.Text;

            switch metodo_3
                case 'Vandermonde'
                    x = app.M_Puntos.Data(1,:);

                    y = app.M_Puntos.Data(2,:);

                    D = Vandermonde(x, y, false);
                    
                    % Obtener el grado del polinomio
                    n = length(D.P);
                    
                    % Crear un string vacío para almacenar el polinomio
                    str = '';
                    
                    % Iterar sobre los coeficientes del vector
                    for i = 1:n
                    
                      % Añadir un término del polinomio al string
                      if i == 1
                        str = sprintf('%.2f', D.P(i));
                      else
                        str = sprintf('%s + (%.2f) * x^(%d)', str, D.P(i), i-1);
                      end
                    end
                    
                    % Eliminar el exponente 0 del primer término
                    if i == 1
                      str = str(1:end-2);
                    end
                    app.Funcion_cap3=str;
                    app.method_result_3.Text = "El polinomio interpolante es: "+str;
                    Funcion=str2sym(str);
                    fplot(app.Grafica_3, Funcion)
                case 'Newton Interpolante'
                    x = app.M_Puntos.Data(1,:);
                    y = app.M_Puntos.Data(2,:);
                    [Tabla] = Newtonint(x,y);
                    [pol] = Newtonor(x,diag(Tabla,+1));
                    polinomio_str = coeficientes_a_polinomio_newton(pol);
                    app.Funcion_cap3=polinomio_str;
                    app.method_result_3.Text = "El polinomio interpolante es: "+polinomio_str;
                    Funcion=str2sym(convertCharsToStrings(polinomio_str));
                    fplot(app.Grafica_3, Funcion)
                case 'Lagrange'
                    x = app.M_Puntos.Data(1,:);
                    y = app.M_Puntos.Data(2,:);
                    [pol] = Lagrange(x,y);
                    polinomio_str = coeficientes_a_polinomio_newton(pol);
                    app.method_result_3.Text = "El polinomio interpolante es: "+polinomio_str;
                    Funcion=str2sym(convertCharsToStrings(polinomio_str));
                    fplot(app.Grafica_3, Funcion)
                    app.Funcion_cap3=polinomio_str;
                    

                case 'Spline'
                    x = app.M_Puntos.Data(1,:);
                    y = app.M_Puntos.Data(2,:);
                    drop_down = app.SplineDropDown.Value;
                    switch drop_down
                        case 'Lineal'
                            d = 1;
                            [Tabla] = Spline(x,y,d);
                            polinomioCompleto = polinomiosSplineLineal(Tabla, x);
                            %app.method_result_3.Text = "El polinomio interpolante es: "+polinomioCompleto;
                        case 'Cubico'
                            d = 3;
                            [Tabla] = Spline(x,y,d);
                            polinomioCompleto = polinomiosSpline(Tabla, x);
                            %app.method_result_3.Text = "El polinomio interpolante es: "+polinomioCompleto;
                    end
                    cla(app.Grafica_3);
                    aux=polinomioCompleto(:,2)';
                    jj=cell2mat(polinomioCompleto(:,3));
                    condiciones=[];
                    syms x
                    for i=1:size(jj,1)
                    aux_1=jj(i,1) < x;
                    aux_2=x < jj(i,2);
                    aux_3=aux_1 & aux_2;
                    condiciones=[condiciones,aux_3];
                    end
                    jh=cell2sym(aux);
                    funciones=matlabFunction(jh);
                    condiciones=matlabFunction(condiciones);
                    sol=@(x)sum(funciones(x).*condiciones(x));
                    sol_f=sol(x);
                    app.Funcion_cap3=sol;
                    fplot(app.Grafica_3,sol_f,'b')
                    
            end
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            metodo=app.Contenedor_metodos_3.SelectedObject.Text;
            syms x
            switch metodo
                case 'Spline'
                    resultado=app.Funcion_cap3(value);
                    hold(app.Grafica_3,"on")
                    plot(app.Grafica_3,value,resultado,'r*')
                    app.Grafica.XLim = [resultado-5;resultado+5];
                otherwise
                    Funcion=str2sym(app.Funcion_cap3);
                    resultado=double(subs(Funcion,x,value));
                    fplot(app.Grafica_3, Funcion)
                    hold(app.Grafica_3,"on")
                    plot(app.Grafica_3,value,resultado,'r*')
                    app.Grafica.XLim = [resultado-5;resultado+5];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Bienvenida = uitab(app.TabGroup);
            app.Bienvenida.Tooltip = {''};
            app.Bienvenida.Title = 'Bienvenida';
            app.GridLayout = uigridlayout(app.Bienvenida);
            app.Titulo = uilabel(app.GridLayout);
            app.Titulo.Interpreter = 'latex';
            app.Titulo.Text = 'Metodos Numericos';
            app.Intro = uilabel(app.GridLayout);
            app.Intro.Interpreter = 'latex';
            app.Intro.Text = 'Bienvenido a nuestra app dedicada al fascinante mundo de los métodos numéricos! Sumérgete en el universo de la resolución de ecuaciones y aproximación de funciones a través de las diversas herramientas y técnicas que ofrecemos en cada uno de nuestros capítulos';
            app.descripcion_cap1 = uilabel(app.GridLayout);
            app.descripcion_cap1.Interpreter = 'latex';
            app.descripcion_cap1.Text = {'\textbf{Capítulo 1: Explorando Raíces}'; ''; 'En esta sección, exploraremos métodos como la Bisección, Punto Fijo, Regla Falsa, Newton, Secante y Raíces Múltiples. Cada uno de ellos te guiará a través de la búsqueda y determinación de las raíces de una función dada. Además, no solo te proporcionaremos los resultados, sino que también podrás observar las tablas de salida que detallan cada paso del proceso.'};
            app.descripcion_cap2 = uilabel(app.GridLayout);
            app.descripcion_cap2.Interpreter = 'latex';
            app.descripcion_cap2.Text = {'\textbf{Capítulo 2: Desentrañando Sistemas Lineales}'; ''; 'Adéntrate en los métodos de Jacobi, Gauss-Seidel y el método SOR (Successive Over-Relaxation). Descubre cómo estos enfoques resuelven sistemas de ecuaciones lineales y obtén información crucial, como el radio espectral, que te indicará la convergencia o divergencia de cada método. Aquí, nos enfocamos en la eficiencia y precisión para ofrecerte resultados confiables.'};
            app.descripcion_cap3 = uilabel(app.GridLayout);
            app.descripcion_cap3.Interpreter = 'latex';
            app.descripcion_cap3.Text = {'\textbf{Capítulo 3: Interpolación y Ajuste de Curvas}'; ''; 'Este fascinante capítulo te sumerge en el emocionante mundo de la interpolación y el ajuste de curvas. Exploraremos métodos como Vandermonde, Newton Interpolante, Lagrange y Splines Lineales y Cúbicos, que desempeñan un papel fundamental en la aproximación de funciones.'};
            app.Cap1Tab = uitab(app.TabGroup);
            app.Cap1Tab.Title = 'Cap 1 (metodos)';
            app.Grafica = uiaxes(app.Cap1Tab);
            title(app.Grafica, 'Funcion')
            xlabel(app.Grafica, 'X')
            ylabel(app.Grafica, 'Y')
            zlabel(app.Grafica, 'Z')
            app.Grafica.XAxisLocation = 'origin';
            app.Grafica.YAxisLocation = 'origin';
            app.FxLabel_2 = uilabel(app.Cap1Tab);
            app.FxLabel_2.Text = 'F(x) =';
            app.Entrada_Fun = uieditfield(app.Cap1Tab, 'text');
            app.ErrorLabel_2 = uilabel(app.Cap1Tab);
            app.ErrorLabel_2.Text = 'Toleracia';
            app.Error = uieditfield(app.Cap1Tab, 'numeric');
            app.Error.Limits = [0 Inf];
            app.Error.Value = 1e-05;
            app.NumIteracionesLabel_2 = uilabel(app.Cap1Tab);
            app.NumIteracionesLabel_2.Text = 'Maximo numero de iteraciones';
            app.Num_Iteraciones = uieditfield(app.Cap1Tab, 'numeric');
            app.Num_Iteraciones.Limits = [1 Inf];
            app.Num_Iteraciones.Value = 100;
            app.TipodeerrorDropDownLabel_2 = uilabel(app.Cap1Tab);
            app.TipodeerrorDropDownLabel_2.Text = 'Tipo de error';
            app.Control_Error = uidropdown(app.Cap1Tab);
            app.Control_Error.Items = {'Absoluto', 'Relativo'};
            app.Control_Error.Value = 'Absoluto';
            app.MultiplicidadSpinnerLabel_2 = uilabel(app.Cap1Tab);
            app.MultiplicidadSpinnerLabel_2.Text = 'Multiplicidad';
            app.Multiplicidad = uispinner(app.Cap1Tab);
            app.Multiplicidad.Limits = [0 Inf];
            app.Multiplicidad.Value = 1;
            app.GxEditFieldLabel_2 = uilabel(app.Cap1Tab);
            app.GxEditFieldLabel_2.Text = 'G(x)';
            app.Entrada_g = uieditfield(app.Cap1Tab, 'text');
            app.PuntoinicialEditFieldLabel_2 = uilabel(app.Cap1Tab);
            app.PuntoinicialEditFieldLabel_2.Text = 'Punto inicial';
            app.Punto_inicial = uieditfield(app.Cap1Tab, 'numeric');
            app.Punto_final = uieditfield(app.Cap1Tab, 'numeric');
            app.PuntofinalEditFieldLabel_2 = uilabel(app.Cap1Tab);
            app.PuntofinalEditFieldLabel_2.Text = 'Punto final';
            app.Boton_resolver = uibutton(app.Cap1Tab, 'push');
            app.Boton_resolver.ButtonPushedFcn = createCallbackFcn(app, @Resolver_Cap_1_ButtonPushed, true);
            app.Boton_resolver.Text = 'Resolver';
            app.Tabla_resultado = uitable(app.Cap1Tab);
            app.Tabla_resultado.ColumnName = {'column1'; 'Column 2'; 'Column 3'; 'Column 4'; 'column 5'; 'column 6'};
            app.Tabla_resultado.RowName = {};
            app.Contenedor_metodos = uibuttongroup(app.Cap1Tab);
            app.Contenedor_metodos.SelectionChangedFcn = createCallbackFcn(app, @Contenedor_metodos_1SelectionChanged, true);
            app.Contenedor_metodos.Title = 'Metodo';
            app.BiseccionButton = uiradiobutton(app.Contenedor_metodos);
            app.BiseccionButton.Text = 'Biseccion';
            app.BiseccionButton.Value = true;
            app.PuntoFijoButton = uiradiobutton(app.Contenedor_metodos);
            app.PuntoFijoButton.Text = 'Punto Fijo';
            app.ReglaFalsaButton = uiradiobutton(app.Contenedor_metodos);
            app.ReglaFalsaButton.Text = 'Regla Falsa';
            app.NewtonButton = uiradiobutton(app.Contenedor_metodos);
            app.NewtonButton.Text = 'Newton';
            app.SecanteButton = uiradiobutton(app.Contenedor_metodos);
            app.SecanteButton.Text = 'Secante';
            app.RaicesMultiplesButton = uiradiobutton(app.Contenedor_metodos);
            app.RaicesMultiplesButton.Text = 'Raices Multiples';
            app.RaicesMultiples2Button = uiradiobutton(app.Contenedor_metodos);
            app.RaicesMultiples2Button.Text = 'Raices Multiples 2';
            app.tab_title = uilabel(app.Cap1Tab);
            app.tab_title.Interpreter = 'latex';
            app.tab_title.Text = '\textbf{Capítulo 1: Explorando Raíces}';
            app.descripcion_method = uilabel(app.Cap1Tab);
            app.descripcion_method.Interpreter = 'latex';
            app.descripcion_method.Text = '';
            app.descripcion_result = uilabel(app.Cap1Tab);
            app.descripcion_result.Interpreter = 'latex';
            app.descripcion_result.Text = '';
            app.Cap2Tab = uitab(app.TabGroup);
            app.Cap2Tab.Title = 'Cap 2';
            app.ContenedorCap2 = uibuttongroup(app.Cap2Tab);
            app.ContenedorCap2.SelectionChangedFcn = createCallbackFcn(app, @Contenedor_metodos_Cap2_SelectionChanged, true);
            app.ContenedorCap2.Title = 'Metodo';
            app.JacobiButton = uiradiobutton(app.ContenedorCap2);
            app.JacobiButton.Text = 'Jacobi';
            app.JacobiButton.Value = true;
            app.GaussSeidelButton = uiradiobutton(app.ContenedorCap2);
            app.GaussSeidelButton.Text = 'Gauss - Seidel';
            app.SORButton = uiradiobutton(app.ContenedorCap2);
            app.SORButton.Text = 'SOR';
            app.DimensinEditFieldLabel = uilabel(app.Cap2Tab);
            app.DimensinEditFieldLabel.Text = 'Dimensión';
            app.Dimension = uieditfield(app.Cap2Tab, 'numeric');
            app.Dimension.ValueChangedFcn = createCallbackFcn(app, @DimensionValueChanged, true);
            app.ALabel = uilabel(app.Cap2Tab);
            app.ALabel.Interpreter = 'latex';
            app.ALabel.Text = 'A =';
            app.Matrizb = uitable(app.Cap2Tab);
            app.Matrizb.ColumnName = '';
            app.Matrizb.RowName = {};
            app.bLabel = uilabel(app.Cap2Tab);
            app.bLabel.Interpreter = 'latex';
            app.bLabel.Text = 'b =';
            app.Matrizx0 = uitable(app.Cap2Tab);
            app.Matrizx0.ColumnName = '';
            app.Matrizx0.RowName = {};
            app.x_0Label = uilabel(app.Cap2Tab);
            app.x_0Label.Interpreter = 'latex';
            app.x_0Label.Text = '$x_0$ =';
            app.MatrizA = uitable(app.Cap2Tab);
            app.MatrizA.ColumnName = '';
            app.MatrizA.RowName = {};
            app.MatrizA.ColumnEditable = true;
            app.NumIteracionesEditFieldLabel = uilabel(app.Cap2Tab);
            app.NumIteracionesEditFieldLabel.Text = 'Num Iteraciones';
            app.NumIteracionesEditField = uieditfield(app.Cap2Tab, 'numeric');
            app.wEditFieldLabel = uilabel(app.Cap2Tab);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.Cap2Tab, 'numeric');
            app.TipodeErrorDropDownLabel = uilabel(app.Cap2Tab);
            app.TipodeErrorDropDownLabel.Text = 'Tipo de Error';
            app.TipodeErrorDropDown = uidropdown(app.Cap2Tab);
            app.TipodeErrorDropDown.Items = {'Absoluto', 'Relativo'};
            app.TipodeErrorDropDown.Value = 'Absoluto';
            app.ErrorEditFieldLabel = uilabel(app.Cap2Tab);
            app.ErrorEditFieldLabel.Text = 'Error';
            app.ErrorEditField = uieditfield(app.Cap2Tab, 'numeric');
            app.ResolverButton = uibutton(app.Cap2Tab, 'push');
            app.ResolverButton.ButtonPushedFcn = createCallbackFcn(app, @Resolver_Cap_2_ButtonPushed, true);
            app.ResolverButton.Text = 'Resolver';
            app.TablaResultados = uitable(app.Cap2Tab);
            app.TablaResultados.ColumnName = '';
            app.TablaResultados.RowName = {};
            app.ResultadosLabel = uilabel(app.Cap2Tab);
            app.ResultadosLabel.Text = 'Resultados';
            app.ErroresLabel = uilabel(app.Cap2Tab);
            app.ErroresLabel.Text = 'Errores';
            app.TablaErrores = uitable(app.Cap2Tab);
            app.TablaErrores.ColumnName = '';
            app.TablaErrores.RowName = {};
            app.tab_title_2 = uilabel(app.Cap2Tab);
            app.tab_title_2.Interpreter = 'latex';
            app.tab_title_2.Text = '\textbf{Capítulo 2: Desentrañando Sistemas}';
            app.descripcion_method_2 = uilabel(app.Cap2Tab);
            app.descripcion_method_2.Interpreter = 'latex';
            app.descripcion_method_2.Text = '';
            app.descripcion_result_2 = uilabel(app.Cap2Tab);
            app.descripcion_result_2.Interpreter = 'latex';
            app.descripcion_result_2.Text = '';
            app.Cap3Tab = uitab(app.TabGroup);
            app.Cap3Tab.Title = 'Cap 3';
            app.Grafica_3 = uiaxes(app.Cap3Tab);
            title(app.Grafica_3, 'Title')
            xlabel(app.Grafica_3, 'X')
            ylabel(app.Grafica_3, 'Y')
            zlabel(app.Grafica_3, 'Z')
            app.Contenedor_metodos_3 = uibuttongroup(app.Cap3Tab);
            app.Contenedor_metodos_3.SelectionChangedFcn = createCallbackFcn(app, @Contenedor_metodos_3SelectionChanged, true);
            app.Contenedor_metodos_3.Title = 'Metodo';
            app.VandermondeButton = uiradiobutton(app.Contenedor_metodos_3);
            app.VandermondeButton.Text = 'Vandermonde';
            app.VandermondeButton.Value = true;
            app.NewtonInterpolanteButton = uiradiobutton(app.Contenedor_metodos_3);
            app.NewtonInterpolanteButton.Text = 'Newton Interpolante';
            app.LagrangeButton = uiradiobutton(app.Contenedor_metodos_3);
            app.LagrangeButton.Text = 'Lagrange';
            app.SplineButton = uiradiobutton(app.Contenedor_metodos_3);
            app.SplineButton.Text = 'Spline';
            app.SplineDropDown = uidropdown(app.Contenedor_metodos_3);
            app.SplineDropDown.Items = {'Lineal', 'Cubico'};
            app.SplineDropDown.ValueChangedFcn = createCallbackFcn(app, @Contenedor_metodos_3SelectionChanged, true);
            app.SplineDropDown.Enable = 'off';
            app.SplineDropDown.Value = 'Lineal';
            app.CantidaddepuntosEditFieldLabel = uilabel(app.Cap3Tab);
            app.CantidaddepuntosEditFieldLabel.Text = 'Cantidad de puntos';
            app.CantidaddepuntosEditField = uieditfield(app.Cap3Tab, 'numeric');
            app.CantidaddepuntosEditField.Limits = [0 Inf];
            app.CantidaddepuntosEditField.ValueChangedFcn = createCallbackFcn(app, @CantidaddepuntosEditFieldValueChanged, true);
            app.M_Puntos = uitable(app.Cap3Tab);
            app.M_Puntos.ColumnName = '';
            app.M_Puntos.RowName = {};
            app.M_Puntos.Enable = 'off';
            app.M_Puntos.Visible = 'off';
            app.Resolver_Cap_3 = uibutton(app.Cap3Tab, 'push');
            app.Resolver_Cap_3.ButtonPushedFcn = createCallbackFcn(app, @Resolver_Cap_3Pushed, true);
            app.Resolver_Cap_3.Enable = 'off';
            app.Resolver_Cap_3.Text = 'Resolver';
            app.tab_title_3 = uilabel(app.Cap3Tab);
            app.tab_title_3.Interpreter = 'latex';
            app.tab_title_3.Text = '\textbf{Capítulo 3: Interpolación y Ajuste de Curvas}';
            app.descripcion_method_3 = uilabel(app.Cap3Tab);
            app.descripcion_method_3.Interpreter = 'latex';
            app.descripcion_method_3.Text = '';
            app.xLabel = uilabel(app.Cap3Tab);
            app.xLabel.Text = 'x';
            app.xEditField = uieditfield(app.Cap3Tab, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.text_4_2 = uilabel(app.Cap3Tab);
            app.text_4_2.Interpreter = 'latex';
            app.text_4_2.Text = 'Valor puntual';
            app.method_result_3 = uilabel(app.Cap3Tab);
            app.method_result_3.Interpreter = 'latex';
            app.method_result_3.Text = 'Solucion';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Metodos_numericos_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end